classdef WindTurbine_GUI_Eurus2026_V1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GeneralMonitoringGraphsTab     matlab.ui.container.Tab
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        PowerElectronicsGraphsTab      matlab.ui.container.Tab
        UIAxes4_2                      matlab.ui.control.UIAxes
        UIAxes3_2                      matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        SafteyCriticalTab              matlab.ui.container.Tab
        PitchAngleField_saftey         matlab.ui.control.NumericEditField
        RPMField_saftey                matlab.ui.control.NumericEditField
        PitchAngleGauge_saftey         matlab.ui.control.Gauge
        PitchAngleGauge_2Label         matlab.ui.control.Label
        TemperatureField_saftey        matlab.ui.control.NumericEditField
        TemperatureGaugeLinear_saftey  matlab.ui.control.LinearGauge
        TemperatureGaugeLabel          matlab.ui.control.Label
        RPMGauge_saftey                matlab.ui.control.Gauge
        RPMGauge_2Label                matlab.ui.control.Label
        TemperatureGraph_saftey        matlab.ui.control.UIAxes
        LatestValuesTab                matlab.ui.container.Tab
        EditField                      matlab.ui.control.NumericEditField
        RPMGauge                       matlab.ui.control.Gauge
        RPMGaugeLabel                  matlab.ui.control.Label
        PitchAngleGauge                matlab.ui.control.Gauge
        PitchAngleGaugeLabel           matlab.ui.control.Label
        PitchAngleEditField            matlab.ui.control.NumericEditField
        TemperatureEditField           matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel      matlab.ui.control.Label
        PowerOutputEditField           matlab.ui.control.NumericEditField
        PowerOutputEditFieldLabel      matlab.ui.control.Label
        PostConverterVoltageEditField  matlab.ui.control.NumericEditField
        PostConverterVoltageEditFieldLabel  matlab.ui.control.Label
        PostConvertCurrentEditField    matlab.ui.control.NumericEditField
        PostConvertCurrentEditFieldLabel  matlab.ui.control.Label
        PreConverterVoltageEditField   matlab.ui.control.NumericEditField
        PreConverterVoltageEditFieldLabel  matlab.ui.control.Label
        PreConverterCurrentEditField   matlab.ui.control.NumericEditField
        PreConverterCurrentLabel       matlab.ui.control.Label
        StopSignalAcknowledgedLamp     matlab.ui.control.Lamp
        StopSignalAcknowledgedLampLabel  matlab.ui.control.Label
        ConnectedtowindturbineLamp     matlab.ui.control.Lamp
        ConnectedtowindturbineLampLabel  matlab.ui.control.Label
        ManualStopButton               matlab.ui.control.Button
        WindTurbineDashboardEurus2026Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        BTDevice
        StartTime
        TimeBuffer = []
        TempBuffer = []
    end
    
    methods (Access = private)
        
        function func(app,src)

           try
               %Ascii being read
               rawAscii = readline(src);

               %Find the start of the packet (Starts with $EURUS)
               if startsWith(rawAscii, "$EURUS,")

                   %Split up the rest of the packet by the delimiters ","
                   data = split(rawAscii, ",");

                   %Packet is always a fixed length of 8, make this check
                   if length(data) == 8

                       typeA = data(2);
                       typeB = data(3);
                       typeC = data(4);

                       dataValues = str2double(data(5:end));
                       currentRPM = dataValues(1);
                       currentPitch = dataValues(2);
                       currentTemp = dataValues(3);
                       isACK = numVals(4);

                       %Check if any of the number values have been
                       %corrupted
                       if ~any(isnan(numVals))

                           if typeA == "A"   
                              %
                              app.RPMField_saftey.Value = currentRPM;
                              app.RPMGauge_saftey.Value = currentRPM;
                              
                           end

                           if typeB == "B"
                              %
                              app.PitchAngleField_saftey.Value = currentPitch;
                              app.PitchAngleGauge_saftey.Value = currentPitch;
                            
                           end

                           if typeC == "C"
                              app.TemperatureField_saftey.Value = currentTemp;
                              app.TemperatureGaugeLinear_saftey.Value = currentTemp;

                              %Need to figure out how to properly plot on
                              %graph
                              %app.TemperatureGraph_saftey = currentTemp;
                       
                           end

                           if isACK == 1
                               app.StopSignalAcknowledgedLamp.Color = [0,1,0];
                           else
                               app.StopSignalAcknowledgedLamp.Color = [0.8,0.8,0.8];
                           end
                                          
                       end 
                   
                   end
               end
           catch
           end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                %We place broadcast name
                app.BTDevice = bluetooth("EURUS_COMHUB_ESP32");
                app.StartTime = datatime('now');
                app.ConnectedtowindturbineLamp.Color = [0 1 0];
                configureCallback(app.BTDevice, "terminator", @(src, event) readTelemetry(app, src));
            catch ME
                app.ConnectedtowindturbineLamp.Color = [1 0 0];
                uialert(app.UIFigure, "Ensure ESP32 is powered and paired to this PC. Error: " + ME.message, "Bluetooth Offline");
            end
        end

        % Button pushed function: ManualStopButton
        function ManualStopButtonPushed(app, event)
            if ~isempty(app.BTDevice)
                writeline(app.BTDevice, "S")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WindTurbineDashboardEurus2026Label = uilabel(app.UIFigure);
            app.WindTurbineDashboardEurus2026Label.Text = ' Wind Turbine Dashboard (Eurus 2026)';
            app.ManualStopButton = uibutton(app.UIFigure, 'push');
            app.ManualStopButton.ButtonPushedFcn = createCallbackFcn(app, @ManualStopButtonPushed, true);
            app.ManualStopButton.Text = 'Manual Stop';
            app.ConnectedtowindturbineLampLabel = uilabel(app.UIFigure);
            app.ConnectedtowindturbineLampLabel.Text = 'Connected to wind turbine';
            app.ConnectedtowindturbineLamp = uilamp(app.UIFigure);
            app.StopSignalAcknowledgedLampLabel = uilabel(app.UIFigure);
            app.StopSignalAcknowledgedLampLabel.Text = 'Stop Signal Acknowledged';
            app.StopSignalAcknowledgedLamp = uilamp(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralMonitoringGraphsTab = uitab(app.TabGroup);
            app.GeneralMonitoringGraphsTab.Title = 'General Monitoring Graphs';
            app.UIAxes = uiaxes(app.GeneralMonitoringGraphsTab);
            title(app.UIAxes, 'RPM')
            xlabel(app.UIAxes, 'Time (Seconds)')
            ylabel(app.UIAxes, 'RPM')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GeneralMonitoringGraphsTab);
            title(app.UIAxes2, 'Temperature')
            xlabel(app.UIAxes2, 'Time (Mintues)')
            ylabel(app.UIAxes2, 'Temperature (Celsius)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_3 = uiaxes(app.GeneralMonitoringGraphsTab);
            title(app.UIAxes_3, 'Output Power')
            xlabel(app.UIAxes_3, 'Time (Second)')
            ylabel(app.UIAxes_3, 'Power (Watt)')
            zlabel(app.UIAxes_3, 'Z')
            app.PowerElectronicsGraphsTab = uitab(app.TabGroup);
            app.PowerElectronicsGraphsTab.Title = 'Power Electronics Graphs';
            app.UIAxes3 = uiaxes(app.PowerElectronicsGraphsTab);
            title(app.UIAxes3, 'Current Before Converter')
            xlabel(app.UIAxes3, 'Time (Seconds)')
            ylabel(app.UIAxes3, 'Current (A)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.PowerElectronicsGraphsTab);
            title(app.UIAxes4, 'Voltage Before Converter')
            xlabel(app.UIAxes4, 'Time (Seconds)')
            ylabel(app.UIAxes4, 'Voltage (V)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes3_2 = uiaxes(app.PowerElectronicsGraphsTab);
            title(app.UIAxes3_2, 'Current After Converter')
            xlabel(app.UIAxes3_2, 'Time (Seconds)')
            ylabel(app.UIAxes3_2, 'Current (A)')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes4_2 = uiaxes(app.PowerElectronicsGraphsTab);
            title(app.UIAxes4_2, 'Voltage After Converter')
            xlabel(app.UIAxes4_2, 'Time (Seconds)')
            ylabel(app.UIAxes4_2, 'Voltage (V)')
            zlabel(app.UIAxes4_2, 'Z')
            app.SafteyCriticalTab = uitab(app.TabGroup);
            app.SafteyCriticalTab.Title = 'Saftey Critical';
            app.TemperatureGraph_saftey = uiaxes(app.SafteyCriticalTab);
            xlabel(app.TemperatureGraph_saftey, 'Time (Mintues)')
            ylabel(app.TemperatureGraph_saftey, 'Temperature (Celsius)')
            zlabel(app.TemperatureGraph_saftey, 'Z')
            app.RPMGauge_2Label = uilabel(app.SafteyCriticalTab);
            app.RPMGauge_2Label.Text = 'RPM';
            app.RPMGauge_saftey = uigauge(app.SafteyCriticalTab, 'circular');
            app.RPMGauge_saftey.Limits = [0 600];
            app.TemperatureGaugeLabel = uilabel(app.SafteyCriticalTab);
            app.TemperatureGaugeLabel.Text = 'Temperature';
            app.TemperatureGaugeLinear_saftey = uigauge(app.SafteyCriticalTab, 'linear');
            app.TemperatureField_saftey = uieditfield(app.SafteyCriticalTab, 'numeric');
            app.PitchAngleGauge_2Label = uilabel(app.SafteyCriticalTab);
            app.PitchAngleGauge_2Label.Text = 'Pitch Angle';
            app.PitchAngleGauge_saftey = uigauge(app.SafteyCriticalTab, 'circular');
            app.PitchAngleGauge_saftey.Limits = [0 70];
            app.RPMField_saftey = uieditfield(app.SafteyCriticalTab, 'numeric');
            app.PitchAngleField_saftey = uieditfield(app.SafteyCriticalTab, 'numeric');
            app.LatestValuesTab = uitab(app.TabGroup);
            app.LatestValuesTab.Title = 'Latest Values';
            app.PreConverterCurrentLabel = uilabel(app.LatestValuesTab);
            app.PreConverterCurrentLabel.Text = 'Pre-Converter Current';
            app.PreConverterCurrentEditField = uieditfield(app.LatestValuesTab, 'numeric');
            app.PreConverterVoltageEditFieldLabel = uilabel(app.LatestValuesTab);
            app.PreConverterVoltageEditFieldLabel.Text = 'Pre-Converter Voltage';
            app.PreConverterVoltageEditField = uieditfield(app.LatestValuesTab, 'numeric');
            app.PostConvertCurrentEditFieldLabel = uilabel(app.LatestValuesTab);
            app.PostConvertCurrentEditFieldLabel.Text = 'Post-Convert Current';
            app.PostConvertCurrentEditField = uieditfield(app.LatestValuesTab, 'numeric');
            app.PostConverterVoltageEditFieldLabel = uilabel(app.LatestValuesTab);
            app.PostConverterVoltageEditFieldLabel.Text = 'Post Converter Voltage';
            app.PostConverterVoltageEditField = uieditfield(app.LatestValuesTab, 'numeric');
            app.PowerOutputEditFieldLabel = uilabel(app.LatestValuesTab);
            app.PowerOutputEditFieldLabel.Text = 'Power Output';
            app.PowerOutputEditField = uieditfield(app.LatestValuesTab, 'numeric');
            app.TemperatureEditFieldLabel = uilabel(app.LatestValuesTab);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.LatestValuesTab, 'numeric');
            app.PitchAngleEditField = uieditfield(app.LatestValuesTab, 'numeric');
            app.PitchAngleGaugeLabel = uilabel(app.LatestValuesTab);
            app.PitchAngleGaugeLabel.Text = 'Pitch Angle';
            app.PitchAngleGauge = uigauge(app.LatestValuesTab, 'circular');
            app.PitchAngleGauge.Limits = [0 70];
            app.RPMGaugeLabel = uilabel(app.LatestValuesTab);
            app.RPMGaugeLabel.Text = 'RPM';
            app.RPMGauge = uigauge(app.LatestValuesTab, 'circular');
            app.RPMGauge.Limits = [0 600];
            app.EditField = uieditfield(app.LatestValuesTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WindTurbine_GUI_Eurus2026_V1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end